classdef MainApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        GridLayout                matlab.ui.container.GridLayout
        LeftPanel                 matlab.ui.container.Panel
        GridLayout13              matlab.ui.container.GridLayout
        LoadButton                matlab.ui.control.Button
        SaveImage                 matlab.ui.control.Button
        TabGroup                  matlab.ui.container.TabGroup
        IntensityTab              matlab.ui.container.Tab
        GridLayout18              matlab.ui.container.GridLayout
        Label78                   matlab.ui.control.Label
        IntensityMethod           matlab.ui.control.DropDown
        GridLayout24              matlab.ui.container.GridLayout
        IntensityPanelBrightness  matlab.ui.container.Panel
        GridLayout70              matlab.ui.container.GridLayout
        Label76                   matlab.ui.control.Label
        IntensityOffsetLabel      matlab.ui.control.Label
        IntensityOffset           matlab.ui.control.Slider
        IntensityAdvanced         matlab.ui.control.CheckBox
        IntensityGainLabel        matlab.ui.control.Label
        IntensityGain             matlab.ui.control.NumericEditField
        IntensityPanelNegative    matlab.ui.container.Panel
        GridLayout67              matlab.ui.container.GridLayout
        Label68                   matlab.ui.control.Label
        IntensityPanelLog         matlab.ui.container.Panel
        GridLayout63              matlab.ui.container.GridLayout
        Label65                   matlab.ui.control.Label
        IntensityLogC             matlab.ui.control.NumericEditField
        IntensityPanelInverseLog  matlab.ui.container.Panel
        GridLayout59              matlab.ui.container.GridLayout
        Label61                   matlab.ui.control.Label
        IntensityInverseLogC      matlab.ui.control.NumericEditField
        IntensityPanelGamma       matlab.ui.container.Panel
        GridLayout53              matlab.ui.container.GridLayout
        Label57                   matlab.ui.control.Label
        IntensityGamma            matlab.ui.control.NumericEditField
        Label55                   matlab.ui.control.Label
        IntensityGammaC           matlab.ui.control.NumericEditField
        IntensityPanelContrast    matlab.ui.container.Panel
        GridLayout42              matlab.ui.container.GridLayout
        Label51                   matlab.ui.control.Label
        Label50                   matlab.ui.control.Label
        Intensityr1               matlab.ui.control.NumericEditField
        Label48                   matlab.ui.control.Label
        Intensityr2               matlab.ui.control.NumericEditField
        Label46                   matlab.ui.control.Label
        Intensitys1               matlab.ui.control.NumericEditField
        Label44                   matlab.ui.control.Label
        Intensitys2               matlab.ui.control.NumericEditField
        IntensityPanelThreshold   matlab.ui.container.Panel
        GridLayout38              matlab.ui.container.GridLayout
        Label40                   matlab.ui.control.Label
        IntensityThreshold        matlab.ui.control.NumericEditField
        IntensityPanelSlicing     matlab.ui.container.Panel
        GridLayout30              matlab.ui.container.GridLayout
        Label36                   matlab.ui.control.Label
        IntensityLower            matlab.ui.control.NumericEditField
        Label34                   matlab.ui.control.Label
        IntensityUpper            matlab.ui.control.NumericEditField
        Label32                   matlab.ui.control.Label
        IntensityBackground       matlab.ui.control.DropDown
        IntensityPanelBitPlane    matlab.ui.container.Panel
        GridLayout26              matlab.ui.container.GridLayout
        Label28                   matlab.ui.control.Label
        IntensityBit              matlab.ui.control.DropDown
        IntensityStatus           matlab.ui.control.Label
        GridLayout19              matlab.ui.container.GridLayout
        IntensityPreview          matlab.ui.control.Button
        IntensityApply            matlab.ui.control.Button
        IntensityCancel           matlab.ui.control.Button
        HistogramTab              matlab.ui.container.Tab
        GridLayout80              matlab.ui.container.GridLayout
        Label93                   matlab.ui.control.Label
        HistogramMethod           matlab.ui.control.DropDown
        HistogramDescription      matlab.ui.control.Label
        HistogramReferencePanel   matlab.ui.container.Panel
        GridLayout87              matlab.ui.container.GridLayout
        HistogramLoadReference    matlab.ui.control.Button
        HistogramReferenceName    matlab.ui.control.Label
        HistogramReferenceAxes    matlab.ui.control.UIAxes
        HistogramStatus           matlab.ui.control.Label
        GridLayout81              matlab.ui.container.GridLayout
        HistogramPreview          matlab.ui.control.Button
        HistogramApply            matlab.ui.control.Button
        HistogramCancel           matlab.ui.control.Button
        FilterTab                 matlab.ui.container.Tab
        GridLayout95              matlab.ui.container.GridLayout
        Label114                  matlab.ui.control.Label
        FilterMethod              matlab.ui.control.DropDown
        FilterDescription         matlab.ui.control.Label
        FilterParameters          matlab.ui.container.GridLayout
        FilterSizeLabel           matlab.ui.control.Label
        FilterSize                matlab.ui.control.DropDown
        FilterSigmaLabel          matlab.ui.control.Label
        FilterSigma               matlab.ui.control.NumericEditField
        FilterSharpenLabel        matlab.ui.control.Label
        FilterSharpen             matlab.ui.control.DropDown
        FilterAlphaLabel          matlab.ui.control.Label
        FilterAlpha               matlab.ui.control.NumericEditField
        FilterMaskLabel           matlab.ui.control.Label
        FilterMask                matlab.ui.control.Table
        FilterStatus              matlab.ui.control.Label
        GridLayout96              matlab.ui.container.GridLayout
        FilterPreview             matlab.ui.control.Button
        FilterApply               matlab.ui.control.Button
        FilterCancel              matlab.ui.control.Button
        UndoButton                matlab.ui.control.Button
        RedoButton                matlab.ui.control.Button
        CenterPanel               matlab.ui.container.Panel
        GridLayout10              matlab.ui.container.GridLayout
        UIAxes_Image              matlab.ui.control.UIAxes
        RightPanel                matlab.ui.container.Panel
        GridLayout4               matlab.ui.container.GridLayout
        HistogramLabel            matlab.ui.control.Label
        UIAxes_Histogram1         matlab.ui.control.UIAxes
        UIAxes_Histogram2         matlab.ui.control.UIAxes
        UIAxes_Histogram3         matlab.ui.control.UIAxes
    end

    properties (Access = private)
        Editor
        isGray = false
        IntensityControls = struct()
        ParameterPanels = struct()
        HistogramControls = struct()
        ReferenceImage = []
        FilterControls = struct()
    end
    methods (Access = private)
        function bindControls(app)
            app.IntensityControls.Method = app.IntensityMethod;
            app.IntensityControls.OffsetLabel = app.IntensityOffsetLabel;
            app.IntensityControls.Offset = app.IntensityOffset;
            app.IntensityControls.Advanced = app.IntensityAdvanced;
            app.IntensityControls.GainLabel = app.IntensityGainLabel;
            app.IntensityControls.Gain = app.IntensityGain;
            app.IntensityControls.LogC = app.IntensityLogC;
            app.IntensityControls.InverseLogC = app.IntensityInverseLogC;
            app.IntensityControls.Gamma = app.IntensityGamma;
            app.IntensityControls.GammaC = app.IntensityGammaC;
            app.IntensityControls.r1 = app.Intensityr1;
            app.IntensityControls.r2 = app.Intensityr2;
            app.IntensityControls.s1 = app.Intensitys1;
            app.IntensityControls.s2 = app.Intensitys2;
            app.IntensityControls.Threshold = app.IntensityThreshold;
            app.IntensityControls.Lower = app.IntensityLower;
            app.IntensityControls.Upper = app.IntensityUpper;
            app.IntensityControls.Background = app.IntensityBackground;
            app.IntensityControls.Bit = app.IntensityBit;
            app.IntensityControls.Status = app.IntensityStatus;
            app.IntensityControls.Preview = app.IntensityPreview;
            app.IntensityControls.Apply = app.IntensityApply;
            app.IntensityControls.Cancel = app.IntensityCancel;
            app.ParameterPanels.Brightness = app.IntensityPanelBrightness;
            app.ParameterPanels.Negative = app.IntensityPanelNegative;
            app.ParameterPanels.Log = app.IntensityPanelLog;
            app.ParameterPanels.InverseLog = app.IntensityPanelInverseLog;
            app.ParameterPanels.Gamma = app.IntensityPanelGamma;
            app.ParameterPanels.Contrast = app.IntensityPanelContrast;
            app.ParameterPanels.Threshold = app.IntensityPanelThreshold;
            app.ParameterPanels.Slicing = app.IntensityPanelSlicing;
            app.ParameterPanels.BitPlane = app.IntensityPanelBitPlane;
            app.HistogramControls.Method = app.HistogramMethod;
            app.HistogramControls.Description = app.HistogramDescription;
            app.HistogramControls.ReferencePanel = app.HistogramReferencePanel;
            app.HistogramControls.LoadReference = app.HistogramLoadReference;
            app.HistogramControls.ReferenceName = app.HistogramReferenceName;
            app.HistogramControls.ReferenceAxes = app.HistogramReferenceAxes;
            app.HistogramControls.Status = app.HistogramStatus;
            app.HistogramControls.Preview = app.HistogramPreview;
            app.HistogramControls.Apply = app.HistogramApply;
            app.HistogramControls.Cancel = app.HistogramCancel;
            app.FilterControls.Method = app.FilterMethod;
            app.FilterControls.Description = app.FilterDescription;
            app.FilterControls.Parameters = app.FilterParameters;
            app.FilterControls.SizeLabel = app.FilterSizeLabel;
            app.FilterControls.Size = app.FilterSize;
            app.FilterControls.SigmaLabel = app.FilterSigmaLabel;
            app.FilterControls.Sigma = app.FilterSigma;
            app.FilterControls.SharpenLabel = app.FilterSharpenLabel;
            app.FilterControls.Sharpen = app.FilterSharpen;
            app.FilterControls.AlphaLabel = app.FilterAlphaLabel;
            app.FilterControls.Alpha = app.FilterAlpha;
            app.FilterControls.MaskLabel = app.FilterMaskLabel;
            app.FilterControls.Mask = app.FilterMask;
            app.FilterControls.Status = app.FilterStatus;
            app.FilterControls.Preview = app.FilterPreview;
            app.FilterControls.Apply = app.FilterApply;
            app.FilterControls.Cancel = app.FilterCancel;
        end
        function resizePanels(app)
            width = app.UIFigure.Position(3);
            height = app.UIFigure.Position(4);
            plots = app.HistogramLabel.Parent;
            if width >= 1100
                app.GridLayout.ColumnWidth = {338, '1x', 310};
                if height >= 640
                    app.GridLayout.RowHeight = {'1x'};
                else
                    app.GridLayout.RowHeight = {640};
                end
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            elseif width >= 720
                app.GridLayout.ColumnWidth = {338, '1x'};
                app.GridLayout.RowHeight = {max(640, height - 260), 260};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = [1 2];
            else
                app.GridLayout.ColumnWidth = {'1x'};
                app.GridLayout.RowHeight = {420, 640, 620};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            end
            axesList = {app.UIAxes_Histogram1, app.UIAxes_Histogram2, app.UIAxes_Histogram3};
            if width >= 720 && width < 1100
                plots.ColumnWidth = {'1x', '1x', '1x'};
                plots.RowHeight = {24, '1x'};
                app.HistogramLabel.Layout.Column = [1 3];
                for k = 1:3
                    axesList{k}.Layout.Row = 2;
                    axesList{k}.Layout.Column = k;
                end
            else
                plots.ColumnWidth = {'1x'};
                plots.RowHeight = {24, '1x', '1x', '1x'};
                app.HistogramLabel.Layout.Column = 1;
                for k = 1:3
                    axesList{k}.Layout.Row = k + 1;
                    axesList{k}.Layout.Column = 1;
                end
            end
        end
        function histogramMethodChanged(app)
            app.cancelPreview();
            controls = app.HistogramControls;
            matching = strcmp(controls.Method.Value, 'Specification / Matching');
            controls.ReferencePanel.Visible = matching;
            if matching
                controls.Description.Text = 'Match the histogram of a reference image. RGB channels are matched separately; grayscale uses a grayscale reference.';
            else
                controls.Description.Text = 'Spread intensities using the cumulative histogram. RGB channels are processed separately.';
            end
        end
        function loadHistogramReference(app)
            [file, folder] = uigetfile( ...
                {'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files'}, ...
                'Choose histogram reference');
            if isequal(file, 0)
                return;
            end
            try
                [img, map] = imread(fullfile(folder, file));
                if ~isempty(map)
                    img = im2uint8(ind2rgb(img, map));
                end
            catch err
                uialert(app.UIFigure, err.message, 'Could not load reference');
                return;
            end
            app.setHistogramReference(img, file);
        end
        function setHistogramReference(app, img, name)
            app.cancelPreview();
            app.ReferenceImage = img;
            app.HistogramControls.ReferenceName.Text = name;
            ax = app.HistogramControls.ReferenceAxes;
            imshow(img, 'Parent', ax);
            ax.Toolbar.Visible = 'off';
            ax.Interactions = [];
            app.previewHistogram();
        end
        function previewHistogram(app)
            if isempty(app.Editor.CurrentImage)
                return;
            end
            matching = strcmp(app.HistogramControls.Method.Value, 'Specification / Matching');
            if matching && isempty(app.ReferenceImage)
                app.cancelPreview();
                app.HistogramControls.Status.Text = 'Load a reference image first.';
                return;
            end
            if matching
                result = HistogramTransforms.match(app.Editor.CurrentImage, app.ReferenceImage);
            else
                result = HistogramTransforms.equalize(app.Editor.CurrentImage);
            end
            app.showPreview(result);
        end
        function filterMethodChanged(app)
            app.cancelPreview();
            c = app.FilterControls;
            method = c.Method.Value;
            sized = ismember(method, {'Mean / Averaging', 'Median', 'Gaussian', 'Unsharp Masking', 'High-Boost'});
            if strcmp(method, 'Median')
                c.SizeLabel.Text = 'Size (odd)';
            else
                c.SizeLabel.Text = 'Mask size';
            end
            gaussian = strcmp(method, 'Gaussian');
            sharpen = strcmp(method, 'High-pass sharpening');
            boost = strcmp(method, 'High-Boost');
            c.SizeLabel.Visible = sized;
            c.Size.Visible = sized;
            c.SigmaLabel.Visible = gaussian;
            c.Sigma.Visible = gaussian;
            c.SharpenLabel.Visible = sharpen;
            c.Sharpen.Visible = sharpen;
            c.AlphaLabel.Visible = boost;
            c.Alpha.Visible = boost;
            c.Parameters.RowHeight = num2cell(32 * [sized gaussian sharpen boost]);
            switch method
                case 'Mean / Averaging'
                    description = 'Average the pixels in a square neighborhood.';
                case 'Median'
                    description = 'Set each pixel to the middle sorted value in its odd-sized square neighborhood.';
                case 'Low-pass'
                    description = 'Weighted 3x3 average: center 2/10, neighbors 1/10.';
                case 'Gaussian'
                    description = 'Gaussian weights; larger sigma gives more blur within the selected mask.';
                case 'High-pass sharpening'
                    description = 'Sharpen with a 3x3 mask whose weights sum to 1.';
                case 'Unsharp Masking'
                    description = 'Sharpened = 2 x image - mean-blurred image.';
                case 'High-Boost'
                    description = 'Result = alpha x image - mean-blurred image.';
            end
            c.Description.Text = [description ' Edges repeat the nearest pixel.'];
            app.showFilterMask();
        end
        function mask = filterMask(app)
            controls = app.FilterControls;
            n = str2double(controls.Size.Value);
            switch controls.Method.Value
                case 'Mean / Averaging'
                    mask = ImageFilters.meanMask(n);
                case 'Median'
                    mask = [];
                case 'Low-pass'
                    mask = ImageFilters.lowPassMask();
                case 'Gaussian'
                    mask = ImageFilters.gaussianMask(n, controls.Sigma.Value);
                case 'High-pass sharpening'
                    neighbors = sscanf(controls.Sharpen.Value, '%d');
                    mask = ImageFilters.sharpenMask(neighbors);
                case 'Unsharp Masking'
                    mask = ImageFilters.unsharpMask(n);
                case 'High-Boost'
                    mask = ImageFilters.highBoostMask(n, controls.Alpha.Value);
            end
        end
        function showFilterMask(app)
            c = app.FilterControls;
            if strcmp(c.Method.Value, 'Median')
                n = str2double(c.Size.Value);
                c.Mask.Data = [];
                c.Mask.Visible = 'off';
                c.MaskLabel.Text = sprintf('Neighborhood %dx%d; median of %d pixels', n, n, n^2);
                return;
            end
            c.Mask.Visible = 'on';
            mask = app.filterMask();
            app.FilterControls.Mask.Data = mask;
            app.FilterControls.Mask.ColumnWidth = repmat({65}, 1, size(mask, 2));
            app.FilterControls.MaskLabel.Text = sprintf('Mask %dx%d; sum = %.4g', ...
                size(mask, 1), size(mask, 2), sum(mask(:)));
        end
        function previewFilter(app)
            app.showFilterMask();
            if isempty(app.Editor.CurrentImage)
                return;
            end
            result = app.transformFilter(app.Editor.CurrentImage);
            app.showPreview(result);
        end
        function result = transformFilter(app, source)
            controls = app.FilterControls;
            if strcmp(controls.Method.Value, 'Median')
                result = ImageFilters.median(source, str2double(controls.Size.Value));
            else
                result = ImageFilters.convolve(source, app.filterMask());
            end
        end
        function methodChanged(app)
            app.cancelPreview();
            keys = fieldnames(app.ParameterPanels);
            for k = 1:numel(keys)
                app.ParameterPanels.(keys{k}).Visible = 'off';
            end
            keys = {'Brightness','Negative','Log','InverseLog','Gamma', ...
                'Contrast','Threshold','Slicing','BitPlane'};
            index = find(strcmp(app.IntensityControls.Method.Items, app.IntensityControls.Method.Value));
            app.ParameterPanels.(keys{index}).Visible = 'on';
        end
        function offsetChanging(app, value)
            app.IntensityControls.OffsetLabel.Text = sprintf('Offset b: %.1f (8-bit levels)', value);
            app.previewIntensity(value);
        end
        function parametersChanged(app)
            controls = app.IntensityControls;
            controls.Gain.Visible = controls.Advanced.Value;
            controls.GainLabel.Visible = controls.Advanced.Value;
            controls.OffsetLabel.Text = sprintf('Offset b: %.1f (8-bit levels)', controls.Offset.Value);
            app.previewIntensity();
        end
        function previewIntensity(app, offset)
            if isempty(app.Editor.CurrentImage)
                return;
            end
            if nargin < 2
                offset = app.IntensityControls.Offset.Value;
            end
            message = app.parameterError();
            if ~isempty(message)
                app.cancelPreview();
                app.IntensityControls.Status.Text = message;
                return;
            end
            % Every preview starts from the last committed array.
            result = app.transformIntensity(app.Editor.CurrentImage, offset);
            app.showPreview(result);
        end
        function message = parameterError(app)
            controls = app.IntensityControls;
            message = '';
            switch controls.Method.Value
                case 'Contrast Stretching'
                    if ~(0 < controls.r1.Value && controls.r1.Value < controls.r2.Value && ...
                            controls.r2.Value < 255 && controls.s1.Value <= controls.s2.Value)
                        message = 'Require 0 < r1 < r2 < 255 and s1 <= s2.';
                    end
                case 'Gray-level Slicing'
                    if controls.Lower.Value > controls.Upper.Value
                        message = 'Lower bound must not exceed upper bound.';
                    end
            end
        end
        function result = transformIntensity(app, source, offset)
            controls = app.IntensityControls;
            switch controls.Method.Value
                case 'Brightness'
                    gain = 1;
                    if controls.Advanced.Value
                        gain = controls.Gain.Value;
                    end
                    result = IntensityTransforms.brightness(source, offset, gain);
                case 'Negative'
                    result = IntensityTransforms.negative(source);
                case 'Log'
                    result = IntensityTransforms.log(source, controls.LogC.Value);
                case 'Inverse Log'
                    result = IntensityTransforms.inverseLog(source, controls.InverseLogC.Value);
                case 'Gamma / Power-law'
                    result = IntensityTransforms.gamma(source, controls.Gamma.Value, controls.GammaC.Value);
                case 'Contrast Stretching'
                    result = IntensityTransforms.contrastStretch(source, ...
                        controls.r1.Value, controls.r2.Value, controls.s1.Value, controls.s2.Value);
                case 'Thresholding'
                    result = IntensityTransforms.threshold(source, controls.Threshold.Value);
                case 'Gray-level Slicing'
                    preserve = strcmp(controls.Background.Value, 'Preserve background');
                    result = IntensityTransforms.grayLevelSlice(source, ...
                        controls.Lower.Value, controls.Upper.Value, preserve);
                case 'Bit-plane Slicing'
                    result = IntensityTransforms.bitPlane(source, str2double(controls.Bit.Value));
            end
        end
        function showPreview(app, image)
            app.Editor.preview(image);
            app.displayImage(image);
            app.setEditStatus('Preview only. Apply commits one undo step.');
            app.updateButtons();
        end
        function applyPreview(app)
            if ~app.Editor.HasPreview
                return;
            end
            app.Editor.apply();
            app.setEditStatus('Applied. Further previews start from this image.');
            app.updateButtons();
        end
        function cancelPreview(app)
            app.Editor.cancel();
            if ~isempty(app.Editor.CurrentImage)
                app.displayImage(app.Editor.CurrentImage);
                app.setEditStatus('Showing committed image.');
            end
            app.updateButtons();
        end
        function undoEdit(app)
            app.Editor.undo();
            app.cancelPreview();
        end
        function redoEdit(app)
            app.Editor.redo();
            app.cancelPreview();
        end
        function setLoadedImage(app, img)
            app.Editor.loadImage(img);
            app.cancelPreview();
        end
        function updateButtons(app)
            loaded = ~isempty(app.Editor.CurrentImage);
            app.UndoButton.Enable = app.Editor.canUndo();
            app.RedoButton.Enable = app.Editor.canRedo();
            app.SaveImage.Enable = loaded;
            controls = app.IntensityControls;
            controls.Method.Enable = loaded;
            controls.Preview.Enable = loaded;
            controls.Apply.Enable = app.Editor.HasPreview;
            controls.Cancel.Enable = app.Editor.HasPreview;
            controls = app.HistogramControls;
            matching = strcmp(controls.Method.Value, 'Specification / Matching');
            controls.Method.Enable = loaded;
            controls.LoadReference.Enable = loaded;
            controls.Preview.Enable = loaded && (~matching || ~isempty(app.ReferenceImage));
            controls.Apply.Enable = app.Editor.HasPreview;
            controls.Cancel.Enable = app.Editor.HasPreview;
            controls = app.FilterControls;
            controls.Method.Enable = loaded;
            controls.Preview.Enable = loaded;
            controls.Apply.Enable = app.Editor.HasPreview;
            controls.Cancel.Enable = app.Editor.HasPreview;
        end
        function setEditStatus(app, message)
            app.IntensityControls.Status.Text = message;
            app.HistogramControls.Status.Text = message;
            app.FilterControls.Status.Text = message;
        end
        function displayImage(app, img)
            imshow(img, 'Parent', app.UIAxes_Image);
            axis(app.UIAxes_Image, 'on');
            axis(app.UIAxes_Image, 'image');
            app.UIAxes_Image.PositionConstraint = 'outerposition';
            title(app.UIAxes_Image, '');
            app.UIAxes_Image.Interactions = [panInteraction zoomInteraction];
            app.isGray = size(img, 3) == 1 || ...
                isequal(img(:,:,1), img(:,:,2), img(:,:,3));
            axesList = {app.UIAxes_Histogram1, app.UIAxes_Histogram2, app.UIAxes_Histogram3};
            colors = {[0.8 0.15 0.15], [0.15 0.6 0.25], [0.2 0.35 0.85]};
            labels = {'Red', 'Green', 'Blue'};
            if app.isGray
                counts = HistogramTransforms.count(img(:,:,1));
            else
                counts = HistogramTransforms.count(img);
            end
            for k = 1:3
                ax = axesList{k};
                cla(ax);
                if app.isGray && k > 1
                    ax.Visible = 'off';
                    title(ax, '');
                    continue;
                end
                ax.Visible = 'on';
                if app.isGray
                    color = [0.4 0.4 0.4];
                    label = 'Grayscale';
                else
                    color = colors{k};
                    label = labels{k};
                end
                bar(ax, 0:255, counts(k,:), 1, 'FaceColor', color, 'EdgeColor', 'none');
                xlim(ax, [0 255]);
                title(ax, label);
                xlabel(ax, 'Intensity (8-bit levels)');
                ylabel(ax, 'Pixels');
                ax.Toolbar.Visible = 'off';
                ax.Interactions = [];
            end
        end
        function saveCommittedImage(app)
            [file, folder] = uiputfile({'*.png','PNG image'; '*.tif','TIFF image'; '*.jpg','JPEG image'}, 'Save committed image');
            if isequal(file, 0)
                return;
            end
            try
                imwrite(app.Editor.CurrentImage, fullfile(folder, file));
            catch err
                uialert(app.UIFigure, err.message, 'Could not save image');
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Editor = ImageEditorState();
            app.bindControls();
            app.UIAxes_Histogram1.Toolbar.Visible = 'off';
            app.UIAxes_Histogram2.Toolbar.Visible = 'off';
            app.UIAxes_Histogram3.Toolbar.Visible = 'off';
            app.methodChanged();
            app.filterMethodChanged();
            axtoolbar(app.UIAxes_Image, {'pan', 'zoomin', 'zoomout', 'restoreview'});
        end

        % Size changed function: UIFigure
        function updateAppLayout(app, event)
            app.resizePanels();
        end

        % Button pushed function: RedoButton
        function RedoButtonButtonPushed(app, event)
            app.redoEdit();
        end

        % Button pushed function: UndoButton
        function UndoButtonButtonPushed(app, event)
            app.undoEdit();
        end

        % Selection change function: TabGroup
        function TabGroupSelectionChanged(app, event)
            app.cancelPreview();
        end

        % Button pushed function: IntensityCancel
        function IntensityCancelButtonPushed(app, event)
            app.cancelPreview();
        end

        % Button pushed function: IntensityApply
        function IntensityApplyButtonPushed(app, event)
            app.applyPreview();
        end

        % Button pushed function: IntensityPreview
        function IntensityPreviewButtonPushed(app, event)
            app.previewIntensity();
        end

        % Value changed function: IntensityBit
        function IntensityBitValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: IntensityBackground
        function IntensityBackgroundValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: IntensityUpper
        function IntensityUpperValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: IntensityLower
        function IntensityLowerValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: IntensityThreshold
        function IntensityThresholdValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: Intensitys2
        function Intensitys2ValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: Intensitys1
        function Intensitys1ValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: Intensityr2
        function Intensityr2ValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: Intensityr1
        function Intensityr1ValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: IntensityGammaC
        function IntensityGammaCValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: IntensityGamma
        function IntensityGammaValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: IntensityInverseLogC
        function IntensityInverseLogCValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: IntensityLogC
        function IntensityLogCValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: IntensityGain
        function IntensityGainValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: IntensityAdvanced
        function IntensityAdvancedValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changed function: IntensityOffset
        function IntensityOffsetValueChanged(app, event)
            app.parametersChanged();
        end

        % Value changing function: IntensityOffset
        function IntensityOffsetValueChanging(app, event)
            app.offsetChanging(event.Value);
        end

        % Value changed function: IntensityMethod
        function IntensityMethodValueChanged(app, event)
            app.methodChanged();
        end

        % Button pushed function: HistogramCancel
        function HistogramCancelButtonPushed(app, event)
            app.cancelPreview();
        end

        % Button pushed function: HistogramApply
        function HistogramApplyButtonPushed(app, event)
            app.applyPreview();
        end

        % Button pushed function: HistogramPreview
        function HistogramPreviewButtonPushed(app, event)
            app.previewHistogram();
        end

        % Button pushed function: HistogramLoadReference
        function HistogramLoadReferenceButtonPushed(app, event)
            app.loadHistogramReference();
        end

        % Value changed function: HistogramMethod
        function HistogramMethodValueChanged(app, event)
            app.histogramMethodChanged();
        end

        % Button pushed function: FilterCancel
        function FilterCancelButtonPushed(app, event)
            app.cancelPreview();
        end

        % Button pushed function: FilterApply
        function FilterApplyButtonPushed(app, event)
            app.applyPreview();
        end

        % Button pushed function: FilterPreview
        function FilterPreviewButtonPushed(app, event)
            app.previewFilter();
        end

        % Value changed function: FilterAlpha
        function FilterAlphaValueChanged(app, event)
            app.previewFilter();
        end

        % Value changed function: FilterSharpen
        function FilterSharpenValueChanged(app, event)
            app.previewFilter();
        end

        % Value changed function: FilterSigma
        function FilterSigmaValueChanged(app, event)
            app.previewFilter();
        end

        % Value changed function: FilterSize
        function FilterSizeValueChanged(app, event)
            app.previewFilter();
        end

        % Value changed function: FilterMethod
        function FilterMethodValueChanged(app, event)
            app.filterMethodChanged();
        end

        % Button pushed function: SaveImage
        function SaveImageButtonPushed(app, event)
            app.saveCommittedImage();
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [file, folder] = uigetfile( ...
                {'*.jpg;*.jpeg;*.png;*.bmp;*.tif;*.tiff', 'Image Files'});
            if isequal(file, 0)
                return;
            end
            try
                [img, map] = imread(fullfile(folder, file));
                if ~isempty(map)
                    img = im2uint8(ind2rgb(img, map));
                end
            catch err
                uialert(app.UIFigure, err.message, 'Could not load image');
                return;
            end
            app.setLoadedImage(img);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Editor';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout4 = uigridlayout(app.RightPanel);
            app.UIAxes_Histogram3 = uiaxes(app.GridLayout4);
            title(app.UIAxes_Histogram3, 'Blue')
            zlabel(app.UIAxes_Histogram3, 'Z')
            app.UIAxes_Histogram2 = uiaxes(app.GridLayout4);
            title(app.UIAxes_Histogram2, 'Green')
            zlabel(app.UIAxes_Histogram2, 'Z')
            app.UIAxes_Histogram1 = uiaxes(app.GridLayout4);
            title(app.UIAxes_Histogram1, 'Red')
            zlabel(app.UIAxes_Histogram1, 'Z')
            app.HistogramLabel = uilabel(app.GridLayout4);
            app.HistogramLabel.Text = 'Histogram';
            app.CenterPanel = uipanel(app.GridLayout);
            app.GridLayout10 = uigridlayout(app.CenterPanel);
            app.UIAxes_Image = uiaxes(app.GridLayout10);
            zlabel(app.UIAxes_Image, 'Z')
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout13 = uigridlayout(app.LeftPanel);
            app.RedoButton = uibutton(app.GridLayout13, 'push');
            app.RedoButton.ButtonPushedFcn = createCallbackFcn(app, @RedoButtonButtonPushed, true);
            app.RedoButton.Enable = 'off';
            app.RedoButton.Text = 'Redo';
            app.UndoButton = uibutton(app.GridLayout13, 'push');
            app.UndoButton.ButtonPushedFcn = createCallbackFcn(app, @UndoButtonButtonPushed, true);
            app.UndoButton.Enable = 'off';
            app.UndoButton.Text = 'Undo';
            app.TabGroup = uitabgroup(app.GridLayout13);
            app.TabGroup.SelectionChangedFcn = createCallbackFcn(app, @TabGroupSelectionChanged, true);
            app.IntensityTab = uitab(app.TabGroup);
            app.IntensityTab.Title = 'Intensity';
            app.GridLayout18 = uigridlayout(app.IntensityTab);
            app.GridLayout19 = uigridlayout(app.GridLayout18);
            app.IntensityCancel = uibutton(app.GridLayout19, 'push');
            app.IntensityCancel.ButtonPushedFcn = createCallbackFcn(app, @IntensityCancelButtonPushed, true);
            app.IntensityCancel.Enable = 'off';
            app.IntensityCancel.Text = 'Cancel';
            app.IntensityApply = uibutton(app.GridLayout19, 'push');
            app.IntensityApply.ButtonPushedFcn = createCallbackFcn(app, @IntensityApplyButtonPushed, true);
            app.IntensityApply.Enable = 'off';
            app.IntensityApply.Text = 'Apply';
            app.IntensityPreview = uibutton(app.GridLayout19, 'push');
            app.IntensityPreview.ButtonPushedFcn = createCallbackFcn(app, @IntensityPreviewButtonPushed, true);
            app.IntensityPreview.Enable = 'off';
            app.IntensityPreview.Text = 'Preview';
            app.IntensityStatus = uilabel(app.GridLayout18);
            app.IntensityStatus.Text = 'Load an image to begin.';
            app.GridLayout24 = uigridlayout(app.GridLayout18);
            app.IntensityPanelBitPlane = uipanel(app.GridLayout24);
            app.IntensityPanelBitPlane.Visible = 'off';
            app.GridLayout26 = uigridlayout(app.IntensityPanelBitPlane);
            app.IntensityBit = uidropdown(app.GridLayout26);
            app.IntensityBit.Items = {'0', '1', '2', '3', '4', '5', '6', '7'};
            app.IntensityBit.ValueChangedFcn = createCallbackFcn(app, @IntensityBitValueChanged, true);
            app.IntensityBit.Value = '7';
            app.Label28 = uilabel(app.GridLayout26);
            app.Label28.Text = '8-bit plane: 0 = least significant, 7 = most significant';
            app.IntensityPanelSlicing = uipanel(app.GridLayout24);
            app.IntensityPanelSlicing.Visible = 'off';
            app.GridLayout30 = uigridlayout(app.IntensityPanelSlicing);
            app.IntensityBackground = uidropdown(app.GridLayout30);
            app.IntensityBackground.Items = {'Preserve background', 'Discard background'};
            app.IntensityBackground.ValueChangedFcn = createCallbackFcn(app, @IntensityBackgroundValueChanged, true);
            app.IntensityBackground.Value = 'Preserve background';
            app.Label32 = uilabel(app.GridLayout30);
            app.Label32.Text = 'Outside selected range';
            app.IntensityUpper = uieditfield(app.GridLayout30, 'numeric');
            app.IntensityUpper.Limits = [0 255];
            app.IntensityUpper.ValueChangedFcn = createCallbackFcn(app, @IntensityUpperValueChanged, true);
            app.IntensityUpper.Value = 180;
            app.Label34 = uilabel(app.GridLayout30);
            app.Label34.Text = 'Upper bound (0-255, inclusive)';
            app.IntensityLower = uieditfield(app.GridLayout30, 'numeric');
            app.IntensityLower.Limits = [0 255];
            app.IntensityLower.ValueChangedFcn = createCallbackFcn(app, @IntensityLowerValueChanged, true);
            app.IntensityLower.Value = 100;
            app.Label36 = uilabel(app.GridLayout30);
            app.Label36.Text = 'Lower bound (0-255, inclusive)';
            app.IntensityPanelThreshold = uipanel(app.GridLayout24);
            app.IntensityPanelThreshold.Visible = 'off';
            app.GridLayout38 = uigridlayout(app.IntensityPanelThreshold);
            app.IntensityThreshold = uieditfield(app.GridLayout38, 'numeric');
            app.IntensityThreshold.Limits = [0 255];
            app.IntensityThreshold.ValueChangedFcn = createCallbackFcn(app, @IntensityThresholdValueChanged, true);
            app.IntensityThreshold.Value = 128;
            app.Label40 = uilabel(app.GridLayout38);
            app.Label40.Text = 'Threshold m (0-255); r >= m is white';
            app.IntensityPanelContrast = uipanel(app.GridLayout24);
            app.IntensityPanelContrast.Visible = 'off';
            app.GridLayout42 = uigridlayout(app.IntensityPanelContrast);
            app.Intensitys2 = uieditfield(app.GridLayout42, 'numeric');
            app.Intensitys2.Limits = [0 255];
            app.Intensitys2.ValueChangedFcn = createCallbackFcn(app, @Intensitys2ValueChanged, true);
            app.Intensitys2.Value = 224;
            app.Label44 = uilabel(app.GridLayout42);
            app.Label44.Text = 's2';
            app.Intensitys1 = uieditfield(app.GridLayout42, 'numeric');
            app.Intensitys1.Limits = [0 255];
            app.Intensitys1.ValueChangedFcn = createCallbackFcn(app, @Intensitys1ValueChanged, true);
            app.Intensitys1.Value = 32;
            app.Label46 = uilabel(app.GridLayout42);
            app.Label46.Text = 's1';
            app.Intensityr2 = uieditfield(app.GridLayout42, 'numeric');
            app.Intensityr2.Limits = [0 255];
            app.Intensityr2.ValueChangedFcn = createCallbackFcn(app, @Intensityr2ValueChanged, true);
            app.Intensityr2.Value = 192;
            app.Label48 = uilabel(app.GridLayout42);
            app.Label48.Text = 'r2';
            app.Intensityr1 = uieditfield(app.GridLayout42, 'numeric');
            app.Intensityr1.Limits = [0 255];
            app.Intensityr1.ValueChangedFcn = createCallbackFcn(app, @Intensityr1ValueChanged, true);
            app.Intensityr1.Value = 64;
            app.Label50 = uilabel(app.GridLayout42);
            app.Label50.Text = 'r1';
            app.Label51 = uilabel(app.GridLayout42);
            app.Label51.Text = 'Piecewise linear; levels 0-255';
            app.IntensityPanelGamma = uipanel(app.GridLayout24);
            app.IntensityPanelGamma.Visible = 'off';
            app.GridLayout53 = uigridlayout(app.IntensityPanelGamma);
            app.IntensityGammaC = uieditfield(app.GridLayout53, 'numeric');
            app.IntensityGammaC.Limits = [0 10];
            app.IntensityGammaC.ValueChangedFcn = createCallbackFcn(app, @IntensityGammaCValueChanged, true);
            app.IntensityGammaC.Value = 1;
            app.Label55 = uilabel(app.GridLayout53);
            app.Label55.Text = 'Scale c (normally 1)';
            app.IntensityGamma = uieditfield(app.GridLayout53, 'numeric');
            app.IntensityGamma.Limits = [0.01 20];
            app.IntensityGamma.ValueChangedFcn = createCallbackFcn(app, @IntensityGammaValueChanged, true);
            app.IntensityGamma.Value = 1;
            app.Label57 = uilabel(app.GridLayout53);
            app.Label57.Text = 'Gamma: s = c r^gamma';
            app.IntensityPanelInverseLog = uipanel(app.GridLayout24);
            app.IntensityPanelInverseLog.Visible = 'off';
            app.GridLayout59 = uigridlayout(app.IntensityPanelInverseLog);
            app.IntensityInverseLogC = uieditfield(app.GridLayout59, 'numeric');
            app.IntensityInverseLogC.Limits = [0 10];
            app.IntensityInverseLogC.ValueChangedFcn = createCallbackFcn(app, @IntensityInverseLogCValueChanged, true);
            app.IntensityInverseLogC.Value = 1;
            app.Label61 = uilabel(app.GridLayout59);
            app.Label61.Text = 'Scale c: s = c (2^r - 1)';
            app.IntensityPanelLog = uipanel(app.GridLayout24);
            app.IntensityPanelLog.Visible = 'off';
            app.GridLayout63 = uigridlayout(app.IntensityPanelLog);
            app.IntensityLogC = uieditfield(app.GridLayout63, 'numeric');
            app.IntensityLogC.Limits = [0 10];
            app.IntensityLogC.ValueChangedFcn = createCallbackFcn(app, @IntensityLogCValueChanged, true);
            app.IntensityLogC.Value = 1;
            app.Label65 = uilabel(app.GridLayout63);
            app.Label65.Text = 'Scale c: s = c log(1 + r) / log(2)';
            app.IntensityPanelNegative = uipanel(app.GridLayout24);
            app.IntensityPanelNegative.Visible = 'off';
            app.GridLayout67 = uigridlayout(app.IntensityPanelNegative);
            app.Label68 = uilabel(app.GridLayout67);
            app.Label68.Text = 's = 1 - r; no parameters.';
            app.IntensityPanelBrightness = uipanel(app.GridLayout24);
            app.GridLayout70 = uigridlayout(app.IntensityPanelBrightness);
            app.IntensityGain = uieditfield(app.GridLayout70, 'numeric');
            app.IntensityGain.Limits = [0 10];
            app.IntensityGain.ValueChangedFcn = createCallbackFcn(app, @IntensityGainValueChanged, true);
            app.IntensityGain.Visible = 'off';
            app.IntensityGain.Value = 1;
            app.IntensityGainLabel = uilabel(app.GridLayout70);
            app.IntensityGainLabel.Visible = 'off';
            app.IntensityGainLabel.Text = 'Gain a';
            app.IntensityAdvanced = uicheckbox(app.GridLayout70);
            app.IntensityAdvanced.ValueChangedFcn = createCallbackFcn(app, @IntensityAdvancedValueChanged, true);
            app.IntensityAdvanced.Text = 'Advanced: use gain a';
            app.IntensityOffset = uislider(app.GridLayout70);
            app.IntensityOffset.Limits = [-100 100];
            app.IntensityOffset.MajorTicks = [-100 -50 0 50 100];
            app.IntensityOffset.ValueChangedFcn = createCallbackFcn(app, @IntensityOffsetValueChanged, true);
            app.IntensityOffset.ValueChangingFcn = createCallbackFcn(app, @IntensityOffsetValueChanging, true);
            app.IntensityOffsetLabel = uilabel(app.GridLayout70);
            app.IntensityOffsetLabel.Text = 'Offset b: 0 (8-bit levels)';
            app.Label76 = uilabel(app.GridLayout70);
            app.Label76.Text = 's = a r + b';
            app.IntensityMethod = uidropdown(app.GridLayout18);
            app.IntensityMethod.Items = {'Brightness', 'Negative', 'Log', 'Inverse Log', 'Gamma / Power-law', 'Contrast Stretching', 'Thresholding', 'Gray-level Slicing', 'Bit-plane Slicing'};
            app.IntensityMethod.ValueChangedFcn = createCallbackFcn(app, @IntensityMethodValueChanged, true);
            app.IntensityMethod.Enable = 'off';
            app.IntensityMethod.Value = 'Brightness';
            app.Label78 = uilabel(app.GridLayout18);
            app.Label78.Text = 'Intensity transformation';
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.GridLayout80 = uigridlayout(app.HistogramTab);
            app.GridLayout81 = uigridlayout(app.GridLayout80);
            app.HistogramCancel = uibutton(app.GridLayout81, 'push');
            app.HistogramCancel.ButtonPushedFcn = createCallbackFcn(app, @HistogramCancelButtonPushed, true);
            app.HistogramCancel.Enable = 'off';
            app.HistogramCancel.Text = 'Cancel';
            app.HistogramApply = uibutton(app.GridLayout81, 'push');
            app.HistogramApply.ButtonPushedFcn = createCallbackFcn(app, @HistogramApplyButtonPushed, true);
            app.HistogramApply.Enable = 'off';
            app.HistogramApply.Text = 'Apply';
            app.HistogramPreview = uibutton(app.GridLayout81, 'push');
            app.HistogramPreview.ButtonPushedFcn = createCallbackFcn(app, @HistogramPreviewButtonPushed, true);
            app.HistogramPreview.Enable = 'off';
            app.HistogramPreview.Text = 'Preview';
            app.HistogramStatus = uilabel(app.GridLayout80);
            app.HistogramStatus.Text = 'Load an image to begin.';
            app.HistogramReferencePanel = uipanel(app.GridLayout80);
            app.HistogramReferencePanel.Visible = 'off';
            app.GridLayout87 = uigridlayout(app.HistogramReferencePanel);
            app.HistogramReferenceAxes = uiaxes(app.GridLayout87);
            app.HistogramReferenceName = uilabel(app.GridLayout87);
            app.HistogramReferenceName.Text = 'No reference selected.';
            app.HistogramLoadReference = uibutton(app.GridLayout87, 'push');
            app.HistogramLoadReference.ButtonPushedFcn = createCallbackFcn(app, @HistogramLoadReferenceButtonPushed, true);
            app.HistogramLoadReference.Enable = 'off';
            app.HistogramLoadReference.Text = 'Load Reference Image';
            app.HistogramDescription = uilabel(app.GridLayout80);
            app.HistogramDescription.Text = 'Spread intensities using the cumulative histogram. RGB channels are processed separately.';
            app.HistogramMethod = uidropdown(app.GridLayout80);
            app.HistogramMethod.Items = {'Equalization', 'Specification / Matching'};
            app.HistogramMethod.ValueChangedFcn = createCallbackFcn(app, @HistogramMethodValueChanged, true);
            app.HistogramMethod.Enable = 'off';
            app.HistogramMethod.Value = 'Equalization';
            app.Label93 = uilabel(app.GridLayout80);
            app.Label93.Text = 'Histogram enhancement';
            app.FilterTab = uitab(app.TabGroup);
            app.FilterTab.Title = 'Filtering';
            app.GridLayout95 = uigridlayout(app.FilterTab);
            app.GridLayout96 = uigridlayout(app.GridLayout95);
            app.FilterCancel = uibutton(app.GridLayout96, 'push');
            app.FilterCancel.ButtonPushedFcn = createCallbackFcn(app, @FilterCancelButtonPushed, true);
            app.FilterCancel.Enable = 'off';
            app.FilterCancel.Text = 'Cancel';
            app.FilterApply = uibutton(app.GridLayout96, 'push');
            app.FilterApply.ButtonPushedFcn = createCallbackFcn(app, @FilterApplyButtonPushed, true);
            app.FilterApply.Enable = 'off';
            app.FilterApply.Text = 'Apply';
            app.FilterPreview = uibutton(app.GridLayout96, 'push');
            app.FilterPreview.ButtonPushedFcn = createCallbackFcn(app, @FilterPreviewButtonPushed, true);
            app.FilterPreview.Enable = 'off';
            app.FilterPreview.Text = 'Preview';
            app.FilterStatus = uilabel(app.GridLayout95);
            app.FilterStatus.Text = 'Load an image to begin.';
            app.FilterMask = uitable(app.GridLayout95);
            app.FilterMask.ColumnName = {};
            app.FilterMask.RowName = {};
            app.FilterMaskLabel = uilabel(app.GridLayout95);
            app.FilterMaskLabel.Text = 'Mask 3x3; sum = 1';
            app.FilterParameters = uigridlayout(app.GridLayout95);
            app.FilterAlpha = uieditfield(app.FilterParameters, 'numeric');
            app.FilterAlpha.Limits = [1 5];
            app.FilterAlpha.ValueChangedFcn = createCallbackFcn(app, @FilterAlphaValueChanged, true);
            app.FilterAlpha.Visible = 'off';
            app.FilterAlpha.Value = 2.4;
            app.FilterAlphaLabel = uilabel(app.FilterParameters);
            app.FilterAlphaLabel.Visible = 'off';
            app.FilterAlphaLabel.Text = 'Alpha';
            app.FilterSharpen = uidropdown(app.FilterParameters);
            app.FilterSharpen.Items = {'4 neighbors', '8 neighbors'};
            app.FilterSharpen.ValueChangedFcn = createCallbackFcn(app, @FilterSharpenValueChanged, true);
            app.FilterSharpen.Visible = 'off';
            app.FilterSharpen.Value = '4 neighbors';
            app.FilterSharpenLabel = uilabel(app.FilterParameters);
            app.FilterSharpenLabel.Visible = 'off';
            app.FilterSharpenLabel.Text = 'Mask';
            app.FilterSigma = uieditfield(app.FilterParameters, 'numeric');
            app.FilterSigma.Limits = [0.1 10];
            app.FilterSigma.ValueChangedFcn = createCallbackFcn(app, @FilterSigmaValueChanged, true);
            app.FilterSigma.Visible = 'off';
            app.FilterSigma.Value = 1;
            app.FilterSigmaLabel = uilabel(app.FilterParameters);
            app.FilterSigmaLabel.Visible = 'off';
            app.FilterSigmaLabel.Text = 'Sigma';
            app.FilterSize = uidropdown(app.FilterParameters);
            app.FilterSize.Items = {'3', '5', '7', '9', '15'};
            app.FilterSize.ValueChangedFcn = createCallbackFcn(app, @FilterSizeValueChanged, true);
            app.FilterSize.Value = '3';
            app.FilterSizeLabel = uilabel(app.FilterParameters);
            app.FilterSizeLabel.Text = 'Mask size';
            app.FilterDescription = uilabel(app.GridLayout95);
            app.FilterDescription.Text = 'Average the pixels in a square neighborhood. Edges repeat the nearest pixel.';
            app.FilterMethod = uidropdown(app.GridLayout95);
            app.FilterMethod.Items = {'Mean / Averaging', 'Median', 'Low-pass', 'Gaussian', 'High-pass sharpening', 'Unsharp Masking', 'High-Boost'};
            app.FilterMethod.ValueChangedFcn = createCallbackFcn(app, @FilterMethodValueChanged, true);
            app.FilterMethod.Enable = 'off';
            app.FilterMethod.Value = 'Mean / Averaging';
            app.Label114 = uilabel(app.GridLayout95);
            app.Label114.Text = 'Filtering with masks';
            app.SaveImage = uibutton(app.GridLayout13, 'push');
            app.SaveImage.ButtonPushedFcn = createCallbackFcn(app, @SaveImageButtonPushed, true);
            app.SaveImage.Enable = 'off';
            app.SaveImage.Text = 'Save Image';
            app.LoadButton = uibutton(app.GridLayout13, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load Image';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MainApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end